classdef FinalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        DHParametersButton              matlab.ui.control.Button
        HomogeneousTransformationsButton  matlab.ui.control.Button
        RotationsandEulersAnglesButton  matlab.ui.control.Button
        KinematicsTab                   matlab.ui.container.Tab
        ForwardkinematicsButton         matlab.ui.control.Button
        WorkSpaceButton                 matlab.ui.control.Button
        InverseKinematicsButton         matlab.ui.control.Button
        DifferentialKinematicsTab       matlab.ui.container.Tab
        InverseDifferentialKinematicsButton  matlab.ui.control.Button
        SingularitiesButton             matlab.ui.control.Button
        JaccobianButton                 matlab.ui.control.Button
        DynamicsTab                     matlab.ui.container.Tab
        ManipulatorDynamicsButton       matlab.ui.control.Button
        ControlTab                      matlab.ui.container.Tab
        GravityCompensationControlButton  matlab.ui.control.Button
        InverseDynamicsControlButton_2  matlab.ui.control.Button
        PlotResultsButton               matlab.ui.control.Button
        PlotResultsButton_2             matlab.ui.control.Button
        ParametersInitializationButton  matlab.ui.control.Button
        ModifyButton                    matlab.ui.control.Button
        ModifyButton_2                  matlab.ui.control.Button
        ModifyButton_3                  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: InverseDynamicsControlButton_2
        function InverseDynamicsControlButton_2Pushed(app, event)
            Parameter
        end

        % Button pushed function: DHParametersButton
        function DHParametersButtonPushed(app, event)
            DHParameter
        end

        % Button pushed function: GravityCompensationControlButton
        function GravityCompensationControlButtonPushed(app, event)
            Parameters
        end

        % Button pushed function: ForwardkinematicsButton
        function ForwardkinematicsButtonPushed(app, event)
            forwkin
        end

        % Button pushed function: WorkSpaceButton
        function WorkSpaceButtonPushed(app, event)
            workspace
        end

        % Button pushed function: InverseDifferentialKinematicsButton
        function InverseDifferentialKinematicsButtonPushed(app, event)
            InverseDiffKinematic
        end

        % Button pushed function: RotationsandEulersAnglesButton
        function RotationsandEulersAnglesButtonPushed(app, event)
            EulerAngles
        end

        % Button pushed function: HomogeneousTransformationsButton
        function HomogeneousTransformationsButtonPushed(app, event)
            HomogenousTransformation
        end

        % Button pushed function: PlotResultsButton
        function PlotResultsButtonPushed(app, event)
            tinv=evalin('base','tinv');
            Xin=evalin('base','Xin');
            Xout=evalin('base','Xout');
            Vin=evalin('base','Vin');
            Vout=evalin('base','Vout');
            Tin=evalin('base','Tin');
            plotq2
        end

        % Button pushed function: ParametersInitializationButton
        function ParametersInitializationButtonPushed(app, event)
            ParametersInitialization
        end

        % Button pushed function: PlotResultsButton_2
        function PlotResultsButton_2Pushed(app, event)
            q=evalin('base','q');
            time=evalin('base','time');
            qdgrav=evalin('base','qdgrav');
            plotq
        end

        % Button pushed function: ModifyButton
        function ModifyButtonPushed(app, event)
            modify
        end

        % Button pushed function: ModifyButton_2
        function ModifyButton_2Pushed(app, event)
            modifyg
        end

        % Button pushed function: ModifyButton_3
        function ModifyButton_3Pushed(app, event)
            modifyi
        end

        % Button pushed function: JaccobianButton
        function JaccobianButtonPushed(app, event)
            Jacob
        end

        % Button pushed function: ManipulatorDynamicsButton
        function ManipulatorDynamicsButtonPushed(app, event)
            Dynamics
        end

        % Button pushed function: SingularitiesButton
        function SingularitiesButtonPushed(app, event)
            singularity
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            InvKin
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.DHParametersButton = uibutton(app.ConfigurationTab, 'push');
            app.DHParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DHParametersButtonPushed, true);
            app.DHParametersButton.Text = 'DH Parameters';
            app.HomogeneousTransformationsButton = uibutton(app.ConfigurationTab, 'push');
            app.HomogeneousTransformationsButton.ButtonPushedFcn = createCallbackFcn(app, @HomogeneousTransformationsButtonPushed, true);
            app.HomogeneousTransformationsButton.Text = 'Homogeneous Transformations';
            app.RotationsandEulersAnglesButton = uibutton(app.ConfigurationTab, 'push');
            app.RotationsandEulersAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @RotationsandEulersAnglesButtonPushed, true);
            app.RotationsandEulersAnglesButton.Text = 'Rotations and Euler''s Angles';
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.ForwardkinematicsButton = uibutton(app.KinematicsTab, 'push');
            app.ForwardkinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardkinematicsButtonPushed, true);
            app.ForwardkinematicsButton.Text = 'Forward kinematics';
            app.WorkSpaceButton = uibutton(app.KinematicsTab, 'push');
            app.WorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @WorkSpaceButtonPushed, true);
            app.WorkSpaceButton.Text = 'Work Space';
            app.InverseKinematicsButton = uibutton(app.KinematicsTab, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.DifferentialKinematicsTab = uitab(app.TabGroup);
            app.DifferentialKinematicsTab.Title = 'Differential Kinematics';
            app.InverseDifferentialKinematicsButton = uibutton(app.DifferentialKinematicsTab, 'push');
            app.InverseDifferentialKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDifferentialKinematicsButtonPushed, true);
            app.InverseDifferentialKinematicsButton.Text = 'Inverse Differential Kinematics';
            app.SingularitiesButton = uibutton(app.DifferentialKinematicsTab, 'push');
            app.SingularitiesButton.ButtonPushedFcn = createCallbackFcn(app, @SingularitiesButtonPushed, true);
            app.SingularitiesButton.Text = 'Singularities';
            app.JaccobianButton = uibutton(app.DifferentialKinematicsTab, 'push');
            app.JaccobianButton.ButtonPushedFcn = createCallbackFcn(app, @JaccobianButtonPushed, true);
            app.JaccobianButton.Text = 'Jaccobian';
            app.DynamicsTab = uitab(app.TabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.ManipulatorDynamicsButton = uibutton(app.DynamicsTab, 'push');
            app.ManipulatorDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @ManipulatorDynamicsButtonPushed, true);
            app.ManipulatorDynamicsButton.Text = 'Manipulator Dynamics';
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GravityCompensationControlButton = uibutton(app.ControlTab, 'push');
            app.GravityCompensationControlButton.ButtonPushedFcn = createCallbackFcn(app, @GravityCompensationControlButtonPushed, true);
            app.GravityCompensationControlButton.Text = 'Gravity Compensation Control';
            app.InverseDynamicsControlButton_2 = uibutton(app.ControlTab, 'push');
            app.InverseDynamicsControlButton_2.ButtonPushedFcn = createCallbackFcn(app, @InverseDynamicsControlButton_2Pushed, true);
            app.InverseDynamicsControlButton_2.Text = 'Inverse Dynamics Control';
            app.PlotResultsButton = uibutton(app.ControlTab, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResultsButtonPushed, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.PlotResultsButton_2 = uibutton(app.ControlTab, 'push');
            app.PlotResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotResultsButton_2Pushed, true);
            app.PlotResultsButton_2.Text = 'Plot Results';
            app.ParametersInitializationButton = uibutton(app.ControlTab, 'push');
            app.ParametersInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersInitializationButtonPushed, true);
            app.ParametersInitializationButton.Text = 'Parameters Initialization';
            app.ModifyButton = uibutton(app.ControlTab, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.ModifyButton.Text = 'Modify';
            app.ModifyButton_2 = uibutton(app.ControlTab, 'push');
            app.ModifyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ModifyButton_2Pushed, true);
            app.ModifyButton_2.Text = 'Modify';
            app.ModifyButton_3 = uibutton(app.ControlTab, 'push');
            app.ModifyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ModifyButton_3Pushed, true);
            app.ModifyButton_3.Text = 'Modify';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FinalGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end